classdef silbido_batch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AudiofilesLabel             matlab.ui.control.Label
        AudioDirectory              matlab.ui.control.EditField
        AnnotationDirectory         matlab.ui.control.EditField
        SelectAnnotationDirectory   matlab.ui.control.Button
        SilbidobatchtoolAnnotatetonalcallsfrommultiplefilesLabel  matlab.ui.control.Label
        InteractiveSessionButton    matlab.ui.control.Button
        SelectAudioDirectory        matlab.ui.control.Button
        ConfigurationDropDown       matlab.ui.control.DropDown
        ConfigurationDropDownLabel  matlab.ui.control.Label
        Switch                      matlab.ui.control.ToggleSwitch
        FilesListBox                matlab.ui.control.ListBox
        ProgressGauge               matlab.ui.control.Gauge
        ProgressGaugeLabel          matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function processing_ui(app, active)
            % processing_ui(app, active)
            % User interface elements should be enabled/disabled depending
            % on whether or not a batch process is active.
            % app - application handle
            % enable - Batch processing active (true) or inactive (false)

            if active
                value = 'off';
                % progress gauge is active when processing
                app.ProgressGauge.Enable = 'on';
                app.ProgressGauge.Value = 0;
                app.Switch.Value = 'Annotate';
            else
                % Not processing
                value = 'on';
                % progress gauge unavailable when we are not processing
                app.ProgressGauge.Enable = 'off';
                app.Switch.Value = 'Off';
            end
            app.FilesListBox.Enable = value;
            app.SelectAudioDirectory.Enable = value;
            app.SelectAnnotationDirectory.Enable = value;
            app.AnnotationDirectory.Enable = value;
            app.AudioDirectory.Enable = value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize_gui(app)
            % See if silbido has been initialized
            import tonals.*;
            if exist('tonals.tonal', 'class') == 0
                silbido_init();
            end

            % Find configuration files and populate pulldown
            [root, ~] = fileparts(mfilename('fullpath'));
            config = findfiles(fullfile(root, "src/matlab/lib"), "*.xml");
            app.ConfigurationDropDown.Items = config;

            % Set default configuration
            idx = find(contains(config, "odontocete_deep"));
            if ~isempty(idx)
                app.ConfigurationDropDown.Value = config(idx(1));
            else
                app.ConfigurationDropDown.Value = config(1);
            end
        end

        % Button pushed function: SelectAudioDirectory
        function SelectAudioDirectoryPushed(app, event)
            folder = uigetdir([], "Select directory to process");
            if isnumeric(folder)
                return  % user canceled
            else
                app.AudioDirectory.Value = folder;
                files = findfiles(folder, "*.wav");
                files = strrep(files, [folder, filesep], "");
                app.FilesListBox.Items = files;
                % Set the annotation directory if not yet set
                if isempty(app.AnnotationDirectory.Value)
                    % default to the audio folder
                    app.AnnotationDirectory.Value = folder;
                end
                app.AudioDirectory.FontColor = [0, 0, 0];
                app.AudioDirectory.Tooltip = "Source audio directory";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'Annotate'
                    % Disable settings user interface elements
                    app.processing_ui(true);

                    % Retrieve files to process
                    process = string(app.FilesListBox.Value);
                   
                    % Create error count and empty array of type exception
                    errors = 0;
                    exceptions = MException.empty(1,0);
                    exceptions_sources = string.empty(1,0);

                    N = length(process);
                    processed = 0;
                    started = tic;
                    for idx = 1:N
                        if strcmp(app.Switch.Value, 'Off')
                            fprintf("Aborted silbido batch processing.\n"); 
                            break;
                        end
                        % Show progress and next file
                        elapsed = toc(started); 
                        fprintf(...
                            "Processed %4d/%d (%d failures). Next: %s %s\n", ...
                            idx-1, N, ...
                            errors, process(idx), duration(0,0,elapsed));

                        % Verify output directory exists
                        [dir, fname, ~] = fileparts(process(idx));
                        outdir = fullfile(app.AnnotationDirectory.Value, dir);
                        if ~ exist(outdir, "dir")
                            mkdir_parents(outdir);
                        end
                        annotation_file = fullfile(outdir, fname+".ann");
                        audio_file = fullfile(app.AudioDirectory.Value, process(idx));
                        try
                            annotations = dtTonalsTracking( ...
                                audio_file, 0, Inf, ...
                                'ParameterSet', app.ConfigurationDropDown.Value);
                            dtTonalsSave(annotation_file, annotations);
%                               switch idx
%                                   case 1
%                                       
%                                   case 2
%                                       throw(MException('boo:bear', '2'));
% 
%                                   case 3
%                                       throw(MException('pooh:bar', '3'));
%                                   case 4
%                               end
                        catch e
                            errors = errors + 1;
                            exceptions(errors) = e; %#ok<AGROW> 
                            exceptions_sources(errors) = process(idx); %#ok<AGROW>
                            fprintf("Error processing %s - %s : %s\n\n", ...
                                process(idx), e.identifier, e.message);
                        end

                        processed = processed + 1;
                        pct = 100 * idx / N;
                        app.ProgressGauge.Value = pct;
                        app.FilesListBox.Value = process(idx+1:end);
                        drawnow();
                    end

                    % Reenable settings elements
                    app.processing_ui(false);  

                    elapsed = toc(started); 
                    if processed < N
                        msg = sprintf("Aborted by user after %d files", processed);
                    else
                        msg = sprintf("Processed %d files", processed);
                    end
                    msg = sprintf("%s.  Elapsed time (h:m:s): %s\n", ...
                        msg, duration(0,0,elapsed));
                    fprintf("Files processed:\n")
                    for pidx = 1:processed
                        fprintf("%5d: %s\n", pidx, process(pidx));
                    end
                    fprintf("%s\n", msg);

                    % Show error summary
                    if errors > 0
                        fprintf("%d errors occurred.  Reports:\n", errors);
                        for eidx = 1:errors
                            fprintf("Error %d while processing %s:\n", ...
                                eidx, exceptions_sources(eidx));
                            fprintf('%s\n', getReport(exceptions(eidx), 'extended'));
                        end

                    end

                case 'Off'
                    % Reenable settings elements, will be done eventually
                    % as we fall out of the processing loop, but will make
                   
                    app.processing_ui(false);
            end

        end

        % Button pushed function: InteractiveSessionButton
        function StartInteractiveSession(app, event)
            
        end

        % Button pushed function: SelectAnnotationDirectory
        function select_annotation_directory(app, event)
            folder = uigetdir([], ...
                "Annotations will be stored in this directory and will mirror the audio directory");
            if isnumeric(folder)
                return  % user canceled
            else
                app.AnnotationDirectory.Value = folder;
            end
        end

        % Value changed function: AudioDirectory
        function AudioDirectoryEntry(app, event)
            value = app.AudioDirectory.Value;
            if exist(value, "dir")
                app.AudioDirectory.FontColor = [0, 0, 0];
                app.AudioDirectory.Tooltip = "Source audio directory";
            else
                app.AudioDirectory.FontColor = [1, 0, 0];
                app.AudioDirectory.Tooltip = "Directory does not exist";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Enable = 'off';
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.UIFigure, 'circular');
            app.ProgressGauge.Enable = 'off';
            app.FilesListBox = uilistbox(app.UIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.Tooltip = {'Press <Control>-a to select all files.'};
            app.FilesListBox.Value = {};
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Off', 'Annotate'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ConfigurationDropDownLabel = uilabel(app.UIFigure);
            app.ConfigurationDropDownLabel.Text = 'Configuration';
            app.ConfigurationDropDown = uidropdown(app.UIFigure);
            app.ConfigurationDropDown.Items = {};
            app.ConfigurationDropDown.Value = {};
            app.SelectAudioDirectory = uibutton(app.UIFigure, 'push');
            app.SelectAudioDirectory.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioDirectoryPushed, true);
            app.SelectAudioDirectory.Tooltip = {'Push to select a directory to be processed.  All wav files under the selected directory will appear in the list and may be selected for processing.  If the directory where annotations are stored has not been set, it will be defaulted to the same directory as selected for audio.'};
            app.SelectAudioDirectory.Text = 'Audio Directory';
            app.InteractiveSessionButton = uibutton(app.UIFigure, 'push');
            app.InteractiveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @StartInteractiveSession, true);
            app.InteractiveSessionButton.Text = 'Interactive Session';
            app.SilbidobatchtoolAnnotatetonalcallsfrommultiplefilesLabel = uilabel(app.UIFigure);
            app.SilbidobatchtoolAnnotatetonalcallsfrommultiplefilesLabel.Text = 'Silbido batch tool:  Automated annotation of tonal calls from multiple files';
            app.SelectAnnotationDirectory = uibutton(app.UIFigure, 'push');
            app.SelectAnnotationDirectory.ButtonPushedFcn = createCallbackFcn(app, @select_annotation_directory, true);
            app.SelectAnnotationDirectory.Tooltip = {'Annotations produced by silbido will be stored here and will mirror the directory structure of the audio directory.'};
            app.SelectAnnotationDirectory.Text = 'Annotation Directory';
            app.AnnotationDirectory = uieditfield(app.UIFigure, 'text');
            app.AnnotationDirectory.Tooltip = {' Annotation files (*.ann) will be saved  to this directory.  Directory will be created if it does not exist.'};
            app.AudioDirectory = uieditfield(app.UIFigure, 'text');
            app.AudioDirectory.ValueChangedFcn = createCallbackFcn(app, @AudioDirectoryEntry, true);
            app.AudioDirectory.Tooltip = {'All .wav files that are underneath this directory will appear in the Audio Files list.  Select files, then flip the Annotate switch.'};
            app.AudiofilesLabel = uilabel(app.UIFigure);
            app.AudiofilesLabel.Text = 'Audio files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = silbido_batch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize_gui)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end